classdef Group13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ColormapsDropDown          matlab.ui.control.DropDown
        ColormapsDropDownLabel     matlab.ui.control.Label
        ShutdownButton             matlab.ui.control.Button
        FinalizeButton             matlab.ui.control.Button
        StdEditField               matlab.ui.control.NumericEditField
        StdEditFieldLabel          matlab.ui.control.Label
        KernelsizeEditField        matlab.ui.control.NumericEditField
        KernelsizeEditFieldLabel   matlab.ui.control.Label
        ThresholdMethod            matlab.ui.control.DropDown
        diagonal_notDiagonal       matlab.ui.control.DropDown
        OperationsDropDown         matlab.ui.control.DropDown
        OperationsDropDownLabel    matlab.ui.control.Label
        ParameterEditField         matlab.ui.control.NumericEditField
        ParameterEditField_2Label  matlab.ui.control.Label
        ProcessButton              matlab.ui.control.Button
        SelectImageButton          matlab.ui.control.Button
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        selectedImage
        HSI
        processedImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.pgm;*.gif', 'Image Files (*.jpg, *.png, *.bmp, *.pgm, *.gif)'; '*.*', 'All Files (*.*)'}, 'Bir görüntü seçin');
            
            if isequal(file, 0)
                return;
            else
                fullFilePath = fullfile(path, file);
                app.selectedImage = imread(fullFilePath);
            end

            app.HSI= rgbtohsi(app.selectedImage);
            %Call the normalizing function
            normalizedImg=mynormalize(app.HSI);
            
            Hue=normalizedImg(:,:,1);
            Saturation=normalizedImg(:,:,2);
            Intensity=normalizedImg(:,:,3);

            imshow(Hue, [],'Parent', app.UIAxes);
            imshow(Saturation, [], 'Parent', app.UIAxes_2);
            imshow(Intensity, [], 'Parent', app.UIAxes_3);

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            selectedOperation = app.OperationsDropDown.Value;
            img=app.HSI(:,:,3);
            
            diagonalOrNot=app.diagonal_notDiagonal.Value;
            threshold=app.ThresholdMethod.Value;
            parameter=app.ParameterEditField.Value;

            if parameter <0
                msgbox('Threshold value cannot be negative!', 'Invalid parameter', 'error');
                return;
            end

            switch selectedOperation
                case 'Prewitt'
                    app.processedImage=myedge(selectedOperation,img,diagonalOrNot,threshold,parameter);
                    imshow(app.processedImage,'Parent',app.UIAxes_3);

                case 'Sobel'
                    app.processedImage=myedge(selectedOperation,img,diagonalOrNot,threshold,parameter);
                    imshow(app.processedImage,'Parent',app.UIAxes_3);

                case 'Kirsch'
                    app.processedImage=myedge(selectedOperation,img,diagonalOrNot,threshold,parameter);
                    imshow(app.processedImage,'Parent',app.UIAxes_3);

                case 'Scharr'


                case 'Laplacian of Gaussian'


                case 'Canny Edge Detector'


            end
        end

        % Clicked callback: ThresholdMethod
        function ThresholdMethodClicked(app, event)
            switch app.ThresholdMethod.Value
                case 'Basic Global Thresholding'
                app.ParameterEditField.Visible="on";
                app.ParameterEditField_2Label.Visible="on";

                case "Otsu's Method"
                app.ParameterEditField.Visible="off";
                app.ParameterEditField_2Label.Visible="off";
            end

        end

        % Value changed function: OperationsDropDown
        function OperationsDropDownValueChanged(app, event)
            switch app.OperationsDropDown.Value
                case 'Prewitt'
                    app.diagonal_notDiagonal.Visible="on";
                    app.ThresholdMethod.Visible="on";
                    app.KernelsizeEditField.Visible="off";
                    app.StdEditField.Visible="off";
                    app.KernelsizeEditFieldLabel.Visible="off";
                    app.StdEditFieldLabel.Visible="off";


                case 'Sobel'
                    app.diagonal_notDiagonal.Visible="on";
                    app.ThresholdMethod.Visible="on";
                    app.KernelsizeEditField.Visible="off";
                    app.StdEditField.Visible="off";
                    app.KernelsizeEditFieldLabel.Visible="off";
                    app.StdEditFieldLabel.Visible="off";


                case 'Kirsch'
                    app.diagonal_notDiagonal.Visible="on";
                    app.ThresholdMethod.Visible="on";
                    app.KernelsizeEditField.Visible="off";
                    app.StdEditField.Visible="off";
                    app.KernelsizeEditFieldLabel.Visible="off";
                    app.StdEditFieldLabel.Visible="off";


                case 'Scharr'
                    app.diagonal_notDiagonal.Visible="on";
                    app.ThresholdMethod.Visible="on";
                    app.KernelsizeEditField.Visible="off";
                    app.StdEditField.Visible="off";
                    app.KernelsizeEditFieldLabel.Visible="off";
                    app.StdEditFieldLabel.Visible="off";

                case 'Laplacian of Gaussian'
                    app.diagonal_notDiagonal.Visible="off";
                    app.ThresholdMethod.Visible="off";
                    app.KernelsizeEditField.Visible="on";
                    app.StdEditField.Visible="on";
                    app.KernelsizeEditFieldLabel.Visible="on";
                    app.StdEditFieldLabel.Visible="on";
                    app.ParameterEditField.Visible="on";
                    app.ParameterEditField_2Label.Visible="on";

                case 'Canny Edge Detector'
                    app.diagonal_notDiagonal.Visible="off";
                    app.ThresholdMethod.Visible="off";
                    app.KernelsizeEditField.Visible="on";
                    app.StdEditField.Visible="on";
                    app.KernelsizeEditFieldLabel.Visible="on";
                    app.StdEditFieldLabel.Visible="on";
                    app.ParameterEditField.Visible="on";
                    app.ParameterEditField_2Label.Visible="on";
            end
        end

        % Button pushed function: FinalizeButton
        function FinalizeButtonPushed(app, event)
            selectedColormap=app.ColormapsDropDown.Value;
            mymorph(selectedColormap,app.processedImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hue')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Saturation')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Intensity')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ParameterEditField_2Label = uilabel(app.UIFigure);
            app.ParameterEditField_2Label.Visible = 'off';
            app.ParameterEditField_2Label.Text = 'Parameter';
            app.ParameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParameterEditField.AllowEmpty = 'on';
            app.ParameterEditField.Visible = 'off';
            app.ParameterEditField.Value = [];
            app.OperationsDropDownLabel = uilabel(app.UIFigure);
            app.OperationsDropDownLabel.Text = 'Operations';
            app.OperationsDropDown = uidropdown(app.UIFigure);
            app.OperationsDropDown.Items = {'Prewitt', 'Sobel', 'Kirsch', 'Scharr', 'Laplacian of Gaussian', 'Canny Edge Detector'};
            app.OperationsDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationsDropDownValueChanged, true);
            app.OperationsDropDown.Value = 'Prewitt';
            app.diagonal_notDiagonal = uidropdown(app.UIFigure);
            app.diagonal_notDiagonal.Items = {'With diagonal', 'Without diagonal'};
            app.diagonal_notDiagonal.Value = 'With diagonal';
            app.ThresholdMethod = uidropdown(app.UIFigure);
            app.ThresholdMethod.Items = {'Basic Global Thresholding', 'Otsu''s Method'};
            app.ThresholdMethod.ClickedFcn = createCallbackFcn(app, @ThresholdMethodClicked, true);
            app.ThresholdMethod.Value = 'Basic Global Thresholding';
            app.KernelsizeEditFieldLabel = uilabel(app.UIFigure);
            app.KernelsizeEditFieldLabel.Visible = 'off';
            app.KernelsizeEditFieldLabel.Text = 'Kernel size';
            app.KernelsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.KernelsizeEditField.AllowEmpty = 'on';
            app.KernelsizeEditField.Visible = 'off';
            app.KernelsizeEditField.Value = [];
            app.StdEditFieldLabel = uilabel(app.UIFigure);
            app.StdEditFieldLabel.Visible = 'off';
            app.StdEditFieldLabel.Text = 'Std';
            app.StdEditField = uieditfield(app.UIFigure, 'numeric');
            app.StdEditField.AllowEmpty = 'on';
            app.StdEditField.Visible = 'off';
            app.StdEditField.Value = [];
            app.FinalizeButton = uibutton(app.UIFigure, 'push');
            app.FinalizeButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeButtonPushed, true);
            app.FinalizeButton.Text = 'Finalize';
            app.ShutdownButton = uibutton(app.UIFigure, 'push');
            app.ShutdownButton.Text = 'Shutdown';
            app.ColormapsDropDownLabel = uilabel(app.UIFigure);
            app.ColormapsDropDownLabel.Text = 'Colormaps';
            app.ColormapsDropDown = uidropdown(app.UIFigure);
            app.ColormapsDropDown.Items = {'Turbo colormap', 'Winter colormap', 'Abyss colormap', 'Cool colormap', 'Jet colormap'};
            app.ColormapsDropDown.Value = 'Turbo colormap';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end